classdef ADCSGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ControlTab                    matlab.ui.container.Tab
        ApplyButton                   matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        SpeedControlAxesSpeedZ        matlab.ui.control.UIAxes
        SpeedControlAxesResponse      matlab.ui.control.UIAxes
        StartReadingButton            matlab.ui.control.Button
        StopReadingButton             matlab.ui.control.Button
        StopPIDButton                 matlab.ui.control.Button
        DesiredSpeedEditField         matlab.ui.control.NumericEditField
        ModeIndecatorLabel            matlab.ui.control.Label
        AngleControlAxesAngle         matlab.ui.control.UIAxes
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        idleLabel                     matlab.ui.control.Label
        ControlModeDropDownLabel      matlab.ui.control.Label
        ControlModeDropDown           matlab.ui.control.DropDown
        Panel                         matlab.ui.container.Panel
        ScanButton                    matlab.ui.control.Button
        DeviceIDLabel                 matlab.ui.control.Label
        DisconnectButton              matlab.ui.control.Button
        DevicesListBoxLabel           matlab.ui.control.Label
        DevicesListBox                matlab.ui.control.ListBox
        ConnectButton                 matlab.ui.control.Button
        SpeedPIDControllerPanel       matlab.ui.container.Panel
        ApplyToSpeedControlPIDButton  matlab.ui.control.Button
        X1000Label                    matlab.ui.control.Label
        X1000Label_2                  matlab.ui.control.Label
        X1000Label_3                  matlab.ui.control.Label
        KEditFieldLabel               matlab.ui.control.Label
        PIDSpeedK                     matlab.ui.control.NumericEditField
        KiEditFieldLabel              matlab.ui.control.Label
        PIDSpeedKi                    matlab.ui.control.NumericEditField
        KpEditFieldLabel              matlab.ui.control.Label
        PIDSpeedKp                    matlab.ui.control.NumericEditField
        MotorPanel                    matlab.ui.container.Panel
        EmergencyStopButton           matlab.ui.control.Button
        RunMotorTestButton            matlab.ui.control.Button
        RunTheMotorAtGivenPWMforTestingLabel  matlab.ui.control.Label
        PWMEditFieldLabel             matlab.ui.control.Label
        PWM                           matlab.ui.control.NumericEditField
        RunMotorButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        BT % BlueTooth Data
        stop % Description
        angleGraph % Description
        animatedLine1
        ax
        reSendCommandFlag;
        dataLog; % Contain all Telemetry Data
        velocityCurve;
        velocityResponseCurve;
        angleCurve;
        i;
        i_old;
        retryingIndex = 1
    end
    
    methods (Access = public)
        
        function LoadingStatus(app, message)
            app.idleLabel.Text = message;
            app.ScanButton.Enable = 'off';
            app.DevicesListBox.Enable = 'off';
            app.StatusLamp.Color = [1, 0.77, 0];
        end
    
        function LoadingComplete(app, message)
            app.idleLabel.Text = message;
            app.ScanButton.Enable = 'on';
            app.DevicesListBox.Enable = 'on';
            app.StatusLamp.Color = 'g';
        end
        
        function ReadData(app)    
            errorNbr = 0;
            while ~app.stop
                
                % Send Read Request
                app.sendReadTelemetryCommand();
                
                % Wait for data and read..
                data    = fread(app.BT, [1 4], 'float');
                
                % Validate Given data
                if(isempty(data))
                    pause(0.01);
                    errorNbr = errorNbr + 1;
                    if(errorNbr > 3)
                       break; 
                    end
                    continue;
                end
                % Storing Data
                timing          = data(1);
                yaw             = data(2);
                speedZ          = data(3);
                speedRes        = data(4);
                app.dataLog.t(app.i) = datenum( timing );
                app.dataLog.yaw(app.i) = yaw;
                app.dataLog.speedZ(app.i) = speedZ;
                app.dataLog.speedRes(app.i) = speedRes;
                
                % Initial Plot
                if (app.i == 1)
                    % Init Velocity Curve
                    app.velocityCurve = plot(app.SpeedControlAxesSpeedZ ,app.dataLog.speedZ, app.dataLog.t);
                    hold(app.SpeedControlAxesSpeedZ, 'on');
                    grid(app.SpeedControlAxesSpeedZ, 'on');
                    app.velocityCurve.XDataSource = 'app.dataLog.t';
                    app.velocityCurve.YDataSource = 'app.dataLog.speedZ';
                    
                    app.angleCurve = plot(app.AngleControlAxesAngle ,app.dataLog.yaw, app.dataLog.t);
                    hold(app.AngleControlAxesAngle, 'on');
                    grid(app.AngleControlAxesAngle, 'on');
                    app.angleCurve.XDataSource = 'app.dataLog.t';
                    app.angleCurve.YDataSource = 'app.dataLog.yaw';
                    
                    app.velocityResponseCurve = plot(app.SpeedControlAxesResponse ,app.dataLog.speedRes, app.dataLog.t);
                    hold(app.SpeedControlAxesResponse, 'on');
                    grid(app.SpeedControlAxesResponse, 'on');
                    app.velocityResponseCurve.XDataSource = 'app.dataLog.t';
                    app.velocityResponseCurve.YDataSource = 'app.dataLog.speedRes';
                else
                    % Refresh Graph every 5 reads
                    if app.i-app.i_old>5
                        if timing>20
                            xlim(app.SpeedControlAxesSpeedZ,[timing-20,timing])
                            xlim(app.SpeedControlAxesResponse,[timing-20,timing])
                            xlim(app.AngleControlAxesAngle,[timing-20,timing])
                        end
                        refreshdata(app.velocityCurve, 'caller');
                        refreshdata(app.velocityResponseCurve, 'caller');
                        refreshdata(app.angleCurve, 'caller');
                        drawnow;
                        app.i_old = app.i ; 
                    end
                end
                app.i = app.i + 1;
            end
            
            command = {'04' '02'};
            app.stop = true;
            sendCommand(app, command);
        end
        
        function sendCommand(app, command)
            flushinput(app.BT);
            flushoutput(app.BT);
            command = hex2dec(app.CommandGenerator(command));
            fwrite(app.BT, command, 'uint16');
        end
        
        function command = CommandGenerator(app, cmd)
            command_ = {'00' '00' '00' '00' '00' '00'};
            command = {'0000', '0000', '0000'};
            for i=1:length(cmd)
                if(length(cmd{i}) > 2)
                    holder = cmd(i);
                    holder = holder{1, 1};
                    command_{2*i-1} = holder(3:4);
                    command_{2*i} = holder(1:2);
                else
                    command_(2*i-1) = cmd(i);   
                end
                command(i) = cmd(i);
            end
            % Generate CRC
            hex = app.CRC16(hex2dec(command_(1:4)));
%             for i=1:4-length(hex) 
%                 hex_ = strcat('0', hex_); 
%             end
            if(length(hex) == 3)
                hex_ = strcat(hex(2:3), strcat('0', hex(1)));
            elseif(length(hex) == 2)
                hex_ = strcat(hex(1:2), strcat('0', '0'));
            elseif(length(hex) == 1)
                hex_ = strcat(strcat('0', hex(1)), strcat('0', '0'));
            else
                hex_ = strcat(hex(3:4), hex(1:2));
            end
            command{3} = hex_;
        end
        
        function [hex] = CRC16(~, data)
            crc = 0;
            for i = 1:length(data)
                crc = bitxor( crc, bitshift(data(i),8) );
                for bit = 1:8
                    if bitand( crc, bitshift(1, 15) )
                      crc = bitxor( bitshift(crc,1), hex2dec('1021') );
                    else
                      crc = bitshift(crc,1);
                    end
                    crc = bitand( crc, hex2dec('ffff') );  % trim to 16 bits
                end
            end
            hex = dec2hex(crc); % maybe to be displayed
        end
        
        function sendReadTelemetryCommand(app)
            %start receive Telemetry
            command = {'00A1' '0001'};
            sendCommand(app, command);
        end
    
        function sendStopReadTelemetryCommand(app)
            %end receive Telemetry
            command = {'00A1' '0002'};
            sendCommand(app, command);
        end
    
        function sendSetPIDSpeedK(app, value)
            %end receive Telemetry
            value = dec2hex(value);
            % prefix the value wit zeros
            for i=1:4-length(value) 
                value = strcat('0', value); 
            end
            command = {'00B1' value};
            sendCommand(app, command);
        end
    
        function sendSetPIDSpeedKi(app, value)
            %end receive Telemetry
            value = dec2hex(value);
            % prefix the value wit zeros
            for i=1:4-length(value) 
                value = strcat('0', value); 
            end
            command = {'00B2' value};
            sendCommand(app, command);
        end
    
        function sendSetPIDSpeedKp(app, value)
            %end receive Telemetry
            value = dec2hex(value);
            % prefix the value wit zeros
            for i=1:4-length(value) 
                value = strcat('0', value); 
            end
            command = {'00B3' value};
            sendCommand(app, command);
        end
   
        function sendRunMotorTest(app)
            command = {'00C1' '0000'};
            sendCommand(app, command);
        end
    
        function sendStopMotor(app)
            command = {'00C2' '0000'};
            sendCommand(app, command);
        end

    
        function sendRunMotorPWM(app, value)
            value = dec2hex(value);
            % prefix the value wit zeros
            for i=1:4-length(value)
                value = strcat('0', value); 
            end
            command = {'00C3' value};
            sendCommand(app, command);
        end
    
        function sendSpeedControlFlag(app, value)
            command = {'00A2' value};
            sendCommand(app, command);
        end
    
        function sendAngleControlFlag(app, value)
            command = {'00A3' value};
            sendCommand(app, command);
        end
    end
    
    methods (Access = private)
        
        function openConnection(app)
            try
                fopen(app.BT);
            catch
                app.retryingIndex = app.retryingIndex + 1 ;
                if app.retryingIndex < 4
                    warning('Retrying...')
                    openConnection(app)
                else
                    errordlg('Connection failed')
                    error('connection failed');
                end
                
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dataLog.t = [];
            app.dataLog.yaw = [];
            app.dataLog.speedZ = [];
            app.i = 1;
            app.i_old = 1;
            
            app.stop = false;
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            LoadingStatus(app, 'Scanning for Available Bluetooth Devices');
            pause(0.2);
            BTdevices = instrhwinfo('bluetooth');
            app.DevicesListBox.Items = BTdevices.RemoteNames;
            app.DevicesListBox.ItemsData = BTdevices.RemoteIDs;
            LoadingComplete(app, 'idle');
        end

        % Value changed function: DevicesListBox
        function DevicesListBoxValueChanged(app, event)
            name = app.DevicesListBox.Value;
            app.DeviceIDLabel.Text = name;
            if(name)
                app.ConnectButton.Enable = 'on';
            else
                app.ConnectButton.Enable = 'off';
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            LoadingStatus(app, "Connecting To: " + app.DevicesListBox.Value);
            app.ConnectButton.Enable = 'off';
            delete(instrfind);
            pause(0.2);
            app.BT = Bluetooth(app.DevicesListBox.Value, 1); % Connect to device
            % Inint Stream Handler
            
            
            openConnection(app) ;
            
            flushinput(app.BT);
            flushoutput(app.BT);
            
            LoadingComplete(app, 'idle');
            app.DevicesListBox.Enable = 'off';
            app.ConnectButton.Visible = 'off';
            app.DisconnectButton.Visible = 'on';
            pause(0.01); % update GUI
            % Start Data Reading Sequance ..
            %app.ReadData();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            LoadingStatus(app, "Disconnecting From: " + app.DevicesListBox.Value);
            fclose(app.BT);
            delete(instrfind);
            LoadingComplete(app, 'idle');
            app.DevicesListBox.Enable = 'on';
            app.ConnectButton.Visible = 'on';
            app.DisconnectButton.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %fclose(app.BT);
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            controlMode = app.ControlModeDropDown.Value;
            if controlMode == "speed"
                LoadingStatus(app, "Set Speed: " + app.DesiredSpeedEditField.Value + " deg/s");
            else
                LoadingStatus(app, "Set Angle: " + app.DesiredSpeedEditField.Value + " deg");
            end
            
            % Send Set Speed Command
            desiredSpeedValueHex = dec2hex(app.DesiredSpeedEditField.Value);
%             if mod(length(dec2hex(app.DesiredSpeedEditField.Value)),2)
%                 desiredSpeedValueHex = horzcat('0',dec2hex(app.DesiredSpeedEditField.Value)) ;
%             else
%                 desiredSpeedValueHex = dec2hex(app.DesiredSpeedEditField.Value) ;
%             end
            for i=1:4-length(desiredSpeedValueHex) 
                desiredSpeedValueHex = strcat('0', desiredSpeedValueHex); 
            end
            %while 1
            
            if controlMode == "speed"
                command = {'0002' desiredSpeedValueHex};
            else
                %send angle
                command = {'0003' desiredSpeedValueHex};
            end
            sendCommand(app, command);
            %end
%             % Print & Verify Speed is Set to Serial Monitor
             %command = {'03' '00'};
             %sendCommand(app, command);
            LoadingComplete(app, 'idle');
        end

        % Button pushed function: StartReadingButton
        function StartReadingButtonPushed(app, event)
            % Send Set Speed Command
            %command = {'04' f'};
            app.stop = false;
            app.dataLog.t = [];
            app.dataLog.yaw = [];
            app.dataLog.speedZ = [];
            app.dataLog.speedRes = [];
            
            app.ReadData();
        end

        % Button pushed function: StopReadingButton
        function StopReadingButtonPushed(app, event)
            %command = {'04' '02'};
            app.stop = true;
            app.i = 1;
            app.i_old = 1;
            sendSpeedControlFlag(app, '0002');
            %sendCommand(app, command);
        end

        % Button pushed function: ApplyToSpeedControlPIDButton
        function ApplyToSpeedControlPIDButtonPushed(app, event)
            sendSetPIDSpeedK(app, app.PIDSpeedK.Value);
            pause(1);
            sendSetPIDSpeedKi(app, app.PIDSpeedKi.Value);
            pause(1);
            sendSetPIDSpeedKp(app, app.PIDSpeedKp.Value);
            pause(1);
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.sendStopMotor();
        end

        % Button pushed function: RunMotorTestButton
        function RunMotorTestButtonPushed(app, event)
            app.sendRunMotorTest();
        end

        % Button pushed function: RunMotorButton
        function RunMotorButtonPushed(app, event)
            app.sendRunMotorPWM(app.PWM.Value);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            controlMode = app.ControlModeDropDown.Value;
            if controlMode == "speed"
                sendSpeedControlFlag(app, '0001');    
            else
                sendAngleControlFlag(app, '0001');
            end
        end

        % Button pushed function: StopPIDButton
        function StopPIDButtonPushed(app, event)
            controlMode = app.ControlModeDropDown.Value;
            if controlMode == "speed"
                sendSpeedControlFlag(app, '0002');    
            else
                sendAngleControlFlag(app, '0002');
            end
        end

        % Value changed function: ControlModeDropDown
        function ControlModeDropDownValueChanged(app, event)
            value = app.ControlModeDropDown.Value;
            if value == "speed"
                app.ModeIndecatorLabel.Text = 'Set Desired Speed';
            else
                app.ModeIndecatorLabel.Text = 'Set Desired Angle';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.ApplyButton = uibutton(app.ControlTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ExecuteButton = uibutton(app.ControlTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SpeedControlAxesSpeedZ = uiaxes(app.ControlTab);
            title(app.SpeedControlAxesSpeedZ, 'Velocity In Z direction')
            xlabel(app.SpeedControlAxesSpeedZ, 'Time (sec)')
            ylabel(app.SpeedControlAxesSpeedZ, 'Velocity (deg/s)')
            app.SpeedControlAxesResponse = uiaxes(app.ControlTab);
            title(app.SpeedControlAxesResponse, 'Speed PID Controller Response')
            xlabel(app.SpeedControlAxesResponse, 'Time (sec)')
            ylabel(app.SpeedControlAxesResponse, 'Control Response')
            app.StartReadingButton = uibutton(app.ControlTab, 'push');
            app.StartReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StartReadingButtonPushed, true);
            app.StartReadingButton.Text = 'Start Reading';
            app.StopReadingButton = uibutton(app.ControlTab, 'push');
            app.StopReadingButton.ButtonPushedFcn = createCallbackFcn(app, @StopReadingButtonPushed, true);
            app.StopReadingButton.Text = 'Stop Reading';
            app.StopPIDButton = uibutton(app.ControlTab, 'push');
            app.StopPIDButton.ButtonPushedFcn = createCallbackFcn(app, @StopPIDButtonPushed, true);
            app.StopPIDButton.Text = 'Stop PID';
            app.DesiredSpeedEditField = uieditfield(app.ControlTab, 'numeric');
            app.ModeIndecatorLabel = uilabel(app.ControlTab);
            app.ModeIndecatorLabel.Text = 'Set Desired Speed';
            app.AngleControlAxesAngle = uiaxes(app.ControlTab);
            title(app.AngleControlAxesAngle, 'Angle About Z direction')
            xlabel(app.AngleControlAxesAngle, 'Time (sec)')
            ylabel(app.AngleControlAxesAngle, 'Yaw Angle')
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.idleLabel = uilabel(app.UIFigure);
            app.idleLabel.Text = 'idle';
            app.ControlModeDropDownLabel = uilabel(app.UIFigure);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.UIFigure);
            app.ControlModeDropDown.Items = {'Speed', 'Angle'};
            app.ControlModeDropDown.ItemsData = {'speed', 'angle'};
            app.ControlModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlModeDropDownValueChanged, true);
            app.ControlModeDropDown.Value = 'speed';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ScanButton = uibutton(app.Panel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DeviceIDLabel = uilabel(app.Panel);
            app.DeviceIDLabel.Text = 'Device ID';
            app.DisconnectButton = uibutton(app.Panel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.DevicesListBoxLabel = uilabel(app.Panel);
            app.DevicesListBoxLabel.Enable = 'off';
            app.DevicesListBoxLabel.Text = 'Devices';
            app.DevicesListBox = uilistbox(app.Panel);
            app.DevicesListBox.Items = {};
            app.DevicesListBox.ValueChangedFcn = createCallbackFcn(app, @DevicesListBoxValueChanged, true);
            app.DevicesListBox.Enable = 'off';
            app.DevicesListBox.Value = {};
            app.ConnectButton = uibutton(app.Panel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.SpeedPIDControllerPanel = uipanel(app.UIFigure);
            app.SpeedPIDControllerPanel.Title = 'Speed PID Controller';
            app.ApplyToSpeedControlPIDButton = uibutton(app.SpeedPIDControllerPanel, 'push');
            app.ApplyToSpeedControlPIDButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyToSpeedControlPIDButtonPushed, true);
            app.ApplyToSpeedControlPIDButton.Text = 'Apply To Speed Control PID';
            app.X1000Label = uilabel(app.SpeedPIDControllerPanel);
            app.X1000Label.Text = 'X1000';
            app.X1000Label_2 = uilabel(app.SpeedPIDControllerPanel);
            app.X1000Label_2.Text = 'X1000';
            app.X1000Label_3 = uilabel(app.SpeedPIDControllerPanel);
            app.X1000Label_3.Text = 'X1000';
            app.KEditFieldLabel = uilabel(app.SpeedPIDControllerPanel);
            app.KEditFieldLabel.Text = 'K';
            app.PIDSpeedK = uieditfield(app.SpeedPIDControllerPanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.SpeedPIDControllerPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.PIDSpeedKi = uieditfield(app.SpeedPIDControllerPanel, 'numeric');
            app.KpEditFieldLabel = uilabel(app.SpeedPIDControllerPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.PIDSpeedKp = uieditfield(app.SpeedPIDControllerPanel, 'numeric');
            app.MotorPanel = uipanel(app.UIFigure);
            app.MotorPanel.Title = 'Motor';
            app.EmergencyStopButton = uibutton(app.MotorPanel, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.RunMotorTestButton = uibutton(app.MotorPanel, 'push');
            app.RunMotorTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunMotorTestButtonPushed, true);
            app.RunMotorTestButton.Text = 'Run Motor Test';
            app.RunTheMotorAtGivenPWMforTestingLabel = uilabel(app.MotorPanel);
            app.RunTheMotorAtGivenPWMforTestingLabel.Text = 'Run The Motor At Given PWM for Testing: ';
            app.PWMEditFieldLabel = uilabel(app.MotorPanel);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWM = uieditfield(app.MotorPanel, 'numeric');
            app.RunMotorButton = uibutton(app.MotorPanel, 'push');
            app.RunMotorButton.ButtonPushedFcn = createCallbackFcn(app, @RunMotorButtonPushed, true);
            app.RunMotorButton.Text = 'Run Motor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ADCSGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end