classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ADCSConnectPanel            matlab.ui.container.Panel
        ScanforDevicesButton        matlab.ui.control.StateButton
        DevicesListBoxLabel         matlab.ui.control.Label
        DevicesListBox              matlab.ui.control.ListBox
        ConnecttotheselecteddeviceButton  matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        IdleLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        DeviceIDLabel               matlab.ui.control.Label
        EgsaListBoxLabel            matlab.ui.control.Label
        EgsaListBox                 matlab.ui.control.ListBox
        ADCSControlPanel            matlab.ui.container.Panel
        DesiredSpeedEditFieldLabel  matlab.ui.control.Label
        DesiredSpeedEditField       matlab.ui.control.NumericEditField
        ApplyButton                 matlab.ui.control.Button
        OptionsDropDownLabel        matlab.ui.control.Label
        OptionsDropDown             matlab.ui.control.DropDown
        StartReadingButton          matlab.ui.control.Button
        ExecuteButton               matlab.ui.control.Button
        StopPIDButton               matlab.ui.control.Button
        StopReadingButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        SatelliteSpeedGaugeLabel    matlab.ui.control.Label
        SatelliteSpeedGauge         matlab.ui.control.Gauge
        SatelliteAngleGaugeLabel    matlab.ui.control.Label
        SatelliteAngleGauge         matlab.ui.control.Gauge
    end

    
    methods (Access = public)
        
        function  LoadingStatus(app, message)
            app.IdleLabel.Text = message;
            app.IdleLabel.BackgroundColor = [0.92,0.81,0.09];
            app.ScanforDevicesButton.Enable = 'off';
            app.DevicesListBox.Enable = 'off';
            app.Lamp.Color = [0.92,0.81,0.09];
            
        end
    
        function LoadingComplete(app,msg)
                app.IdleLabel.Text = msg;
                app.IdleLabel.BackgroundColor = [0.10,0.98,0.58];
                app.ScanforDevicesButton.Enable = 'on';
                app.DevicesListBox.Enable = 'on';
                app.Lamp.Color = [0.10,0.98,0.58];
        end

    end
    

    methods (Access = private)

        % Value changed function: ScanforDevicesButton
        function ScanforDevicesButtonValueChanged(app, event)
            LoadingStatus(app,"Scanning for available devices ");
            pause(0.2);
            BT_devices = instrhwinfo('Bluetooth');
            app.DevicesListBox.Items = BT_devices.RemoteNames;
            app.DevicesListBox.ItemsData = BT_devices.RemoteIDs;
            LoadingComplete(app,"Idle");
            
        end

        % Value changed function: DevicesListBox
        function DevicesListBoxValueChanged(app, event)
            name = app.DevicesListBox.Value;
            app.Label.Text = name;
            if(name)
                app.ConnecttotheselecteddeviceButton.Enable = 'on';
            else
                app.ConnecttotheselecteddeviceButton.Enable = 'off';
            end
            
        end

        % Value changed function: EgsaListBox
        function EgsaListBoxValueChanged(app, event)
            name__ = app.EgsaListBox.Value;
            if(name__=='connect')
                app.ADCSConnectPanel.Visible='on';
                app.ADCSControlPanel.Visible='off';
            else
                app.ADCSConnectPanel.Visible='off';
                app.ADCSControlPanel.Visible='on';
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ADCSConnectPanel = uipanel(app.UIFigure);
            app.ADCSConnectPanel.Title = 'ADCS Connect';
            app.ScanforDevicesButton = uibutton(app.ADCSConnectPanel, 'state');
            app.ScanforDevicesButton.ValueChangedFcn = createCallbackFcn(app, @ScanforDevicesButtonValueChanged, true);
            app.ScanforDevicesButton.Text = 'Scan for Devices';
            app.DevicesListBoxLabel = uilabel(app.ADCSConnectPanel);
            app.DevicesListBoxLabel.Text = 'Devices';
            app.DevicesListBox = uilistbox(app.ADCSConnectPanel);
            app.DevicesListBox.Items = {};
            app.DevicesListBox.ValueChangedFcn = createCallbackFcn(app, @DevicesListBoxValueChanged, true);
            app.DevicesListBox.Value = {};
            app.ConnecttotheselecteddeviceButton = uibutton(app.ADCSConnectPanel, 'push');
            app.ConnecttotheselecteddeviceButton.Text = 'Connect to the selected device';
            app.Lamp = uilamp(app.ADCSConnectPanel);
            app.IdleLabel = uilabel(app.ADCSConnectPanel);
            app.IdleLabel.Text = 'Idle';
            app.Label = uilabel(app.ADCSConnectPanel);
            app.Label.Text = '';
            app.DeviceIDLabel = uilabel(app.ADCSConnectPanel);
            app.DeviceIDLabel.Text = 'Device ID';
            app.EgsaListBoxLabel = uilabel(app.UIFigure);
            app.EgsaListBoxLabel.Text = 'Egsa';
            app.EgsaListBox = uilistbox(app.UIFigure);
            app.EgsaListBox.Items = {'connect', 'control', '', ''};
            app.EgsaListBox.ValueChangedFcn = createCallbackFcn(app, @EgsaListBoxValueChanged, true);
            app.EgsaListBox.Value = {};
            app.ADCSControlPanel = uipanel(app.UIFigure);
            app.ADCSControlPanel.Title = 'ADCS Control';
            app.DesiredSpeedEditFieldLabel = uilabel(app.ADCSControlPanel);
            app.DesiredSpeedEditFieldLabel.Text = 'Desired Speed';
            app.DesiredSpeedEditField = uieditfield(app.ADCSControlPanel, 'numeric');
            app.ApplyButton = uibutton(app.ADCSControlPanel, 'push');
            app.ApplyButton.Text = 'Apply';
            app.OptionsDropDownLabel = uilabel(app.ADCSControlPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.ADCSControlPanel);
            app.StartReadingButton = uibutton(app.ADCSControlPanel, 'push');
            app.StartReadingButton.Text = 'Start Reading';
            app.ExecuteButton = uibutton(app.ADCSControlPanel, 'push');
            app.ExecuteButton.Text = 'Execute';
            app.StopPIDButton = uibutton(app.ADCSControlPanel, 'push');
            app.StopPIDButton.Text = 'Stop PID';
            app.StopReadingButton = uibutton(app.ADCSControlPanel, 'push');
            app.StopReadingButton.Text = 'Stop Reading';
            app.UIAxes = uiaxes(app.ADCSControlPanel);
            title(app.UIAxes, 'Velocity In Z direction')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Velocity (deg/s)')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2 = uiaxes(app.ADCSControlPanel);
            title(app.UIAxes2, 'Speed PID Controller Response')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Control Response')
            app.UIAxes3 = uiaxes(app.ADCSControlPanel);
            title(app.UIAxes3, 'Angle in z')
            xlabel(app.UIAxes3, 'Angle')
            ylabel(app.UIAxes3, 'time(sec)')
            app.SatelliteSpeedGaugeLabel = uilabel(app.ADCSControlPanel);
            app.SatelliteSpeedGaugeLabel.Text = 'Satellite Speed';
            app.SatelliteSpeedGauge = uigauge(app.ADCSControlPanel, 'circular');
            app.SatelliteAngleGaugeLabel = uilabel(app.ADCSControlPanel);
            app.SatelliteAngleGaugeLabel.Text = 'Satellite Angle';
            app.SatelliteAngleGauge = uigauge(app.ADCSControlPanel, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end